classdef NeuronTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tracking                        matlab.ui.Figure
        InitializationPanel             matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        ImageFolderEditFieldLabel       matlab.ui.control.Label
        ImageFolderEditField            matlab.ui.control.EditField
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        FrameRangeEditFieldLabel        matlab.ui.control.Label
        FrameRangeEditField             matlab.ui.control.NumericEditField
        NeuronIndexEditFieldLabel       matlab.ui.control.Label
        NeuronIndexEditField            matlab.ui.control.EditField
        StartFrameEditFieldLabel        matlab.ui.control.Label
        StartFrameEditField             matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        GroupTrackingTab                matlab.ui.container.Tab
        GroupTrackingButton             matlab.ui.control.Button
        UpdateMappedPosButton           matlab.ui.control.Button
        SingleTrackingTab               matlab.ui.container.Tab
        SingleTrackingButton            matlab.ui.control.Button
        AnchorIndexEditFieldLabel       matlab.ui.control.Label
        AnchorIndexEditField            matlab.ui.control.NumericEditField
        SearchIntervalEditFieldLabel    matlab.ui.control.Label
        SearchIntervalEditField         matlab.ui.control.NumericEditField
        RatioEditFieldLabel             matlab.ui.control.Label
        RatioEditField                  matlab.ui.control.NumericEditField
        TrackingModeDropDownLabel       matlab.ui.control.Label
        TrackingModeDropDown            matlab.ui.control.DropDown
        RepeatTrackingTab               matlab.ui.container.Tab
        RepeatTrackingButton            matlab.ui.control.Button
        SearchIntervalEditField_2Label  matlab.ui.control.Label
        SearchIntervalEditField_2       matlab.ui.control.NumericEditField
        RatioEditField_2Label           matlab.ui.control.Label
        RatioEditField_2                matlab.ui.control.NumericEditField
        RepeatIntervalEditFieldLabel    matlab.ui.control.Label
        RepeatIntervalEditField         matlab.ui.control.NumericEditField
        PostProcessingPanel             matlab.ui.container.Panel
        CombineNeuronsButton            matlab.ui.control.Button
        UpdateNeuronPosButton           matlab.ui.control.Button
        MakeNeuronTrackingVideoButton   matlab.ui.control.Button
        Red2GreenButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImgFolder = '';
        FrameRange = [];
        FirstFrame = 1;
        Channel = '';
        TrackingMode = '';
        TrackingNeuronIndex = [];
        AnchorIndex = [];
        SearchInterval = 0;
        IntensityRatio = 1;
        RedSeq = nan;
        GreenSeq = nan;
        SyncStruc = nan;
    end
    
    methods (Access = private)
        
        
        function GetInput(app)
            app.FirstFrame = app.StartFrameEditField.Value;
            app.Channel = app.ChannelDropDown.Value;
            start_frame = app.FrameRangeEditField.Value;
            end_frame = app.EditField.Value;
            app.FrameRange = [start_frame, end_frame];
            app.AnchorIndex = app.AnchorIndexEditField.Value;
            if (app.AnchorIndex == 0)
                app.AnchorIndex = [];
            end
            app.TrackingMode = app.TrackingModeDropDown.Value;
            strTrackingIndex = app.NeuronIndexEditField.Value;
            app.TrackingNeuronIndex = str2num(strTrackingIndex);
            disp('Tracking Neuorn Index');
            disp(app.TrackingNeuronIndex);
        end
    end
    

    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadImageFolder(app, event)
            if isempty(app.ImgFolder)
                app.ImgFolder = pwd;
            end
            selpath = uigetdir(app.ImgFolder,'Selecting Data Folder');
            app.ImgFolder = [selpath '\'];
            app.ImageFolderEditField.Value = app.ImgFolder;
            
            % load sync structure
            sync_struc_data = load([app.ImgFolder 'sync_struc.mat']);
            app.RedSeq = sync_struc_data.rfp_seq;
            app.GreenSeq = sync_struc_data.gcamp_seq;
            app.SyncStruc = sync_struc_data.sync_struc;
        end

        % Button pushed function: GroupTrackingButton
        function OnGroupTracking(app, event)
            app.GetInput();
            
            frame_seq = app.FrameRange(1):app.FrameRange(2);
            GroupExtractBasedTracking_V1(app.ImgFolder,app.TrackingNeuronIndex,...
                app.Channel,frame_seq);
        end

        % Button pushed function: SingleTrackingButton
        function OnSingleTracking(app, event)
            app.GetInput();
            
            search_interval = app.SearchIntervalEditField.Value;
            intensity_ratio = app.RatioEditField.Value;
            params = [1, search_interval, intensity_ratio];
            frame_seq = app.FrameRange(1):app.FrameRange(2);
            
%             track_single_neuron(app.ImgFolder,app.TrackingNeuronIndex,app.AnchorIndex,...
%                 app.FirstFrame,app.Channel,frame_seq,params);
            TrackSingleNeuron_Extraction(app.ImgFolder,[app.AnchorIndex,app.TrackingNeuronIndex(1)],...
                app.FirstFrame,frame_seq,app.Channel,params,app.TrackingMode);
        end

        % Button pushed function: RepeatTrackingButton
        function OnRepeatTracking(app, event)
            app.GetInput();
            
            repeat_interval = app.RepeatIntervalEditField.Value;
            search_interval = app.SearchIntervalEditField_2.Value;
            intensity_ratio = app.RatioEditField_2.Value;
            params = [repeat_interval, search_interval, intensity_ratio];
            frame_seq = app.FrameRange(1):app.FrameRange(2);
            
            UpdateRepeatedNeuronPos(app.ImgFolder,app.TrackingNeuronIndex,app.AnchorIndex,...
                app.Channel,app.FirstFrame,frame_seq,params);
        end

        % Button pushed function: UpdateNeuronPosButton
        function OnUpdateNeuronPos(app, event)
            app.GetInput();
            
            frame_seq = app.FrameRange(1):app.FrameRange(2);
            update_neuronpos(app.ImgFolder,app.TrackingNeuronIndex,app.Channel,...
                app.FirstFrame,frame_seq);
        end

        % Button pushed function: CombineNeuronsButton
        function OnCombineNeurons(app, event)
            app.GetInput();
            
            pos_folder = [app.ImgFolder 'neuron_pos\' app.Channel '\'];
            combined_filename = [app.ImgFolder 'neuron_pos\' app.Channel '.txt'];
            track_combine(pos_folder,combined_filename);
        end

        % Button pushed function: MakeNeuronTrackingVideoButton
        function OnMakeNeuronTrackingVideo(app, event)
            app.GetInput();
        end

        % Button pushed function: Red2GreenButton
        function OnMapRed2Green(app, event)
            app.GetInput();
            
            rfp_frames = app.FrameRange(1):app.FrameRange(2);
            MapRFP2GCaMP(app.ImgFolder,app.TrackingNeuronIndex,rfp_frames);
        end

        % Button pushed function: UpdateMappedPosButton
        function UpdateMappedPosButtonPushed(app, event)
            app.GetInput();
            
            if strcmp(app.Channel,'green') ~= 1
                disp('Only used in green channe;');
                return;
            end
            frame_seq = app.FrameRange(1):app.FrameRange(2);
            GroupExtractBasedGreenTracking(app.ImgFolder,app.TrackingNeuronIndex,...
                app.FirstFrame,frame_seq);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Tracking = uifigure;
            app.Tracking.Name = 'UI Figure';
            app.InitializationPanel = uipanel(app.Tracking);
            app.InitializationPanel.Title = 'Initialization';
            app.LoadButton = uibutton(app.InitializationPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageFolder, true);
            app.LoadButton.Text = 'Load';
            app.EditField = uieditfield(app.InitializationPanel, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.Value = 1;
            app.ImageFolderEditFieldLabel = uilabel(app.InitializationPanel);
            app.ImageFolderEditFieldLabel.Text = 'Image Folder';
            app.ImageFolderEditField = uieditfield(app.InitializationPanel, 'text');
            app.ChannelDropDownLabel = uilabel(app.InitializationPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.InitializationPanel);
            app.ChannelDropDown.Items = {'red', 'green'};
            app.ChannelDropDown.Value = 'red';
            app.FrameRangeEditFieldLabel = uilabel(app.InitializationPanel);
            app.FrameRangeEditFieldLabel.Text = 'Frame Range';
            app.FrameRangeEditField = uieditfield(app.InitializationPanel, 'numeric');
            app.FrameRangeEditField.Limits = [1 Inf];
            app.FrameRangeEditField.ValueDisplayFormat = '%.0f';
            app.FrameRangeEditField.Value = 1;
            app.NeuronIndexEditFieldLabel = uilabel(app.InitializationPanel);
            app.NeuronIndexEditFieldLabel.Text = 'Neuron Index';
            app.NeuronIndexEditField = uieditfield(app.InitializationPanel, 'text');
            app.StartFrameEditFieldLabel = uilabel(app.InitializationPanel);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.InitializationPanel, 'numeric');
            app.StartFrameEditField.ValueDisplayFormat = '%.0f';
            app.TabGroup = uitabgroup(app.Tracking);
            app.GroupTrackingTab = uitab(app.TabGroup);
            app.GroupTrackingTab.Title = 'Group Tracking';
            app.GroupTrackingButton = uibutton(app.GroupTrackingTab, 'push');
            app.GroupTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @OnGroupTracking, true);
            app.GroupTrackingButton.Text = 'Group Tracking';
            app.UpdateMappedPosButton = uibutton(app.GroupTrackingTab, 'push');
            app.UpdateMappedPosButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMappedPosButtonPushed, true);
            app.UpdateMappedPosButton.Text = 'Update Mapped Pos';
            app.SingleTrackingTab = uitab(app.TabGroup);
            app.SingleTrackingTab.Title = 'Single Tracking';
            app.SingleTrackingButton = uibutton(app.SingleTrackingTab, 'push');
            app.SingleTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @OnSingleTracking, true);
            app.SingleTrackingButton.Text = 'Single Tracking';
            app.AnchorIndexEditFieldLabel = uilabel(app.SingleTrackingTab);
            app.AnchorIndexEditFieldLabel.Text = 'Anchor Index';
            app.AnchorIndexEditField = uieditfield(app.SingleTrackingTab, 'numeric');
            app.AnchorIndexEditField.Limits = [1 Inf];
            app.AnchorIndexEditField.ValueDisplayFormat = '%.0f';
            app.AnchorIndexEditField.Value = 1;
            app.SearchIntervalEditFieldLabel = uilabel(app.SingleTrackingTab);
            app.SearchIntervalEditFieldLabel.Text = 'Search Interval';
            app.SearchIntervalEditField = uieditfield(app.SingleTrackingTab, 'numeric');
            app.SearchIntervalEditField.Limits = [0 Inf];
            app.SearchIntervalEditField.ValueDisplayFormat = '%.1f';
            app.SearchIntervalEditField.Value = 6;
            app.RatioEditFieldLabel = uilabel(app.SingleTrackingTab);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.SingleTrackingTab, 'numeric');
            app.RatioEditField.Limits = [0 1];
            app.RatioEditField.ValueDisplayFormat = '%.1f';
            app.RatioEditField.Value = 0.5;
            app.TrackingModeDropDownLabel = uilabel(app.SingleTrackingTab);
            app.TrackingModeDropDownLabel.Text = 'Tracking Mode';
            app.TrackingModeDropDown = uidropdown(app.SingleTrackingTab);
            app.TrackingModeDropDown.Items = {'update', 'create'};
            app.TrackingModeDropDown.Value = 'update';
            app.RepeatTrackingTab = uitab(app.TabGroup);
            app.RepeatTrackingTab.Title = 'Repeat Tracking';
            app.RepeatTrackingButton = uibutton(app.RepeatTrackingTab, 'push');
            app.RepeatTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @OnRepeatTracking, true);
            app.RepeatTrackingButton.Text = 'Repeat Tracking';
            app.SearchIntervalEditField_2Label = uilabel(app.RepeatTrackingTab);
            app.SearchIntervalEditField_2Label.Text = 'Search Interval';
            app.SearchIntervalEditField_2 = uieditfield(app.RepeatTrackingTab, 'numeric');
            app.SearchIntervalEditField_2.Limits = [0 Inf];
            app.SearchIntervalEditField_2.ValueDisplayFormat = '%.1f';
            app.SearchIntervalEditField_2.Value = 6;
            app.RatioEditField_2Label = uilabel(app.RepeatTrackingTab);
            app.RatioEditField_2Label.Text = 'Ratio';
            app.RatioEditField_2 = uieditfield(app.RepeatTrackingTab, 'numeric');
            app.RatioEditField_2.Limits = [0 1];
            app.RatioEditField_2.ValueDisplayFormat = '%.1f';
            app.RatioEditField_2.Value = 0.5;
            app.RepeatIntervalEditFieldLabel = uilabel(app.RepeatTrackingTab);
            app.RepeatIntervalEditFieldLabel.Text = 'Repeat Interval';
            app.RepeatIntervalEditField = uieditfield(app.RepeatTrackingTab, 'numeric');
            app.RepeatIntervalEditField.Limits = [1 Inf];
            app.RepeatIntervalEditField.ValueDisplayFormat = '%.0f';
            app.RepeatIntervalEditField.Value = 1;
            app.PostProcessingPanel = uipanel(app.Tracking);
            app.PostProcessingPanel.Title = 'Post Processing';
            app.CombineNeuronsButton = uibutton(app.PostProcessingPanel, 'push');
            app.CombineNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @OnCombineNeurons, true);
            app.CombineNeuronsButton.Text = 'Combine Neurons';
            app.UpdateNeuronPosButton = uibutton(app.PostProcessingPanel, 'push');
            app.UpdateNeuronPosButton.ButtonPushedFcn = createCallbackFcn(app, @OnUpdateNeuronPos, true);
            app.UpdateNeuronPosButton.Text = 'Update Neuron Pos';
            app.MakeNeuronTrackingVideoButton = uibutton(app.PostProcessingPanel, 'push');
            app.MakeNeuronTrackingVideoButton.ButtonPushedFcn = createCallbackFcn(app, @OnMakeNeuronTrackingVideo, true);
            app.MakeNeuronTrackingVideoButton.Text = 'Make Neuron Tracking Video';
            app.Red2GreenButton = uibutton(app.PostProcessingPanel, 'push');
            app.Red2GreenButton.ButtonPushedFcn = createCallbackFcn(app, @OnMapRed2Green, true);
            app.Red2GreenButton.Text = 'Red2Green';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NeuronTracking

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tracking)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tracking)
        end
    end
end